classdef Lamp_Label < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                   matlab.ui.Figure
        Label                      matlab.ui.control.Label
        Lamp                       matlab.ui.control.Lamp
        LampLabel                  matlab.ui.control.Label
        SensorInputEditField       matlab.ui.control.NumericEditField
        SensorInputEditFieldLabel  matlab.ui.control.Label
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Value changed function: SensorInputEditField
        function SensorInputEditFieldValueChanged(app, event)
            value = app.SensorInputEditField.Value;
            
            switch value

                case 10
                    app.Lamp.Color=[0.00, 0.00, 1.00];
                    app.Label.Text = 'LOW';
                
                case 20
                    app.Lamp.Color=[0.00, 1.00, 0.00];
                    app.Label.Text = 'MEDIUM';
                
                case 30 
                    app.Lamp.Color=[1.00, 0.00, 0.00];
                    app.Label.Text = 'HIGH';
                
                    
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.SensorInputEditFieldLabel = uilabel(app.UIFigure);
            app.SensorInputEditFieldLabel.Text = 'Sensor Input';
            app.SensorInputEditField = uieditfield(app.UIFigure, 'numeric');
            app.SensorInputEditField.ValueChangedFcn = createCallbackFcn(app, @SensorInputEditFieldValueChanged, true);
            app.LampLabel = uilabel(app.UIFigure);
            app.LampLabel.Text = 'Lamp';
            app.Lamp = uilamp(app.UIFigure);
            app.Label = uilabel(app.UIFigure);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Lamp_Label

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end